classdef ElementCreationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElementCreatorUIFigure         matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        LensTab                        matlab.ui.container.Tab
        LensOptional                   matlab.ui.container.Panel
        LensRequired                   matlab.ui.container.Panel
        LensAptDiameterEditFieldUnit   matlab.ui.control.Label
        LensFocalLengthEditFieldUnit   matlab.ui.control.Label
        LensDistanceEditFieldUnit      matlab.ui.control.Label
        LensDistanceEditField          matlab.ui.control.NumericEditField
        DistanceLabel                  matlab.ui.control.Label
        LensAptDiameterEditField       matlab.ui.control.NumericEditField
        AptDiameterEditFieldLabel      matlab.ui.control.Label
        LensFocalLengthEditField       matlab.ui.control.NumericEditField
        FocalLengthEditFieldLabel      matlab.ui.control.Label
        LensButton                     matlab.ui.control.Button
        SourceTab                      matlab.ui.container.Tab
        SourceOptional                 matlab.ui.container.Panel
        SourceRequired                 matlab.ui.container.Panel
        SourceWavelengthEditFieldUnit  matlab.ui.control.Label
        SourceWavelengthEditField      matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel       matlab.ui.control.Label
        SourceButton                   matlab.ui.control.Button
        DetectorTab                    matlab.ui.container.Tab
        DetectorOptional               matlab.ui.container.Panel
        DetectorRequired               matlab.ui.container.Panel
        DetectorPixelPitchEditFieldUnit  matlab.ui.control.Label
        DetectorResolutionEditFieldUnit  matlab.ui.control.Label
        DetectorDistanceEditFieldUnit  matlab.ui.control.Label
        DetectorDistanceEditField      matlab.ui.control.NumericEditField
        DistanceLabel_2                matlab.ui.control.Label
        DetectorPixelPitchEditField    matlab.ui.control.NumericEditField
        PixelPitchEditFieldLabel       matlab.ui.control.Label
        DetectorResolutionEditField    matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel       matlab.ui.control.Label
        DetectorButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        dataOut = struct();
        dataIn = struct();
        mode = 'create';
        typeToTab = containers.Map;
    end
    
    methods (Access = public)
        
        function terminate(app)
            uiresume(app.ElementCreatorUIFigure);
        end
        
        function passData(app, data)
            app.dataIn = data;

            tabName = app.typeToTab(data.el.elementType);
            app.TabGroup.SelectedTab = app.(tabName);

            app.setTabData(data);
            app.setMode('edit');
        end

        function setMode(app, type)
            app.mode = type;

            buttons = {app.LensButton, app.SourceButton, app.DetectorButton};
            switch type
                case 'create'
                    newVal = 'Create';
                case 'edit'
                    newVal = 'Apply';
                otherwise
                    error('Unknown type: %s', type);
            end

            for i = 1:numel(buttons)
                btn = buttons{i};
                btn.Text = newVal;
            end
        end
        
        function setTabData(app, data)
            switch data.el.elementType
                case 'lens'
                    app.LensDistanceEditField.Value = data.dist;
                    app.LensAptDiameterEditField.Value = data.el.apertureParams.radius;
                    app.LensFocalLengthEditField.Value = data.el.focalLength;
                case 'source'
                    app.SourceDistanceEditField.Value = data.dist;
                    app.SourceWavelengthEditField.Value = data.el.wavelength;
                case 'detector'
                    app.DetectorDistanceEditField.Value = data.dist;
                    app.DetectorPixelPitchEditField.Value = data.el.pixelPitch;
                    app.DetectorResolutionEditField.Value = data.el.resolution(1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(0, 'ScreenSize');
            figWidth = app.ElementCreatorUIFigure.Position(3);
            figHeight = app.ElementCreatorUIFigure.Position(4);
            app.ElementCreatorUIFigure.Position(1) = (screenSize(3) - figWidth) / 2;
            app.ElementCreatorUIFigure.Position(2) = (screenSize(4) - figHeight) / 2;

            app.typeToTab('lens') = 'LensTab';
            app.typeToTab('source') = 'SourceTab';
            app.typeToTab('detector') = 'DetectorTab';
        end

        % Button pushed function: SourceButton
        function SourceButtonPushed(app, event)
            switch app.mode
                case 'create'
                    app.dataOut.elementType = "source";
                    app.dataOut.wavelength = app.SourceWavelengthEditField.Value * osf.utils.unitToScale(app.SourceWavelengthEditFieldUnit.Text);
                case 'edit'
                    el = app.dataIn.el;
                    el.wavelength = app.SourceWavelengthEditField.Value;
            end

            app.terminate();
        end

        % Button pushed function: LensButton
        function LensButtonPushed(app, event)
            switch app.mode
                case 'create'
                    app.dataOut.elementType = "lens";
                    app.dataOut.dist = app.LensDistanceEditField.Value * osf.utils.unitToScale(app.LensDistanceEditFieldUnit.Text);
                    app.dataOut.focalLength = app.LensFocalLengthEditField.Value * osf.utils.unitToScale(app.LensFocalLengthEditFieldUnit.Text);
                    app.dataOut.apertureSize = app.LensAptDiameterEditField.Value * osf.utils.unitToScale(app.LensAptDiameterEditFieldUnit.Text);
                case 'edit'
                    app.dataOut.dist = app.LensDistanceEditField.Value * osf.utils.unitToScale(app.LensDistanceEditFieldUnit.Text);
                    el = app.dataIn.el;
                    el.focalLength = app.LensFocalLengthEditField.Value * osf.utils.unitToScale(app.LensFocalLengthEditFieldUnit.Text);
                    el.apertureParams.radius = app.LensAptDiameterEditField.Value * osf.utils.unitToScale(app.LensAptDiameterEditFieldUnit.Text);
            end

            app.terminate();
        end

        % Button pushed function: DetectorButton
        function DetectorButtonPushed(app, event)
            switch app.mode
                case 'create'
                    app.dataOut.elementType = "detector";
                    app.dataOut.dist = app.DetectorDistanceEditField.Value * osf.utils.unitToScale(app.DetectorDistanceEditFieldUnit.Text);
                    app.dataOut.varargin = {};
        
                    if ~isempty(app.DetectorPixelPitchEditField.Value)
                        app.dataOut.varargin = [app.dataOut.varargin, {'pixelPitch', app.DetectorPixelPitchEditField.Value * osf.utils.unitToScale(app.DetectorPixelPitchEditFieldUnit.Text)}];
                    end
                    if ~isempty(app.DetectorResolutionEditField.Value)
                        app.dataOut.varargin = [app.dataOut.varargin, {'resolution', app.DetectorResolutionEditField.Value * osf.utils.unitToScale(app.DetectorResolutionEditFieldUnit.Text)}];
                    end
                case 'edit'
                    app.dataOut.dist = app.DetectorDistanceEditField.Value * osf.utils.unitToScale(app.DetectorDistanceEditFieldUnit.Text);
                    el = app.dataIn.el;
                    el.pixelPitch = app.DetectorPixelPitchEditField.Value * osf.utils.unitToScale(app.DetectorPixelPitchEditFieldUnit.Text);
                    resVal = app.DetectorResolutionEditField.Value * osf.utils.unitToScale(app.DetectorResolutionEditFieldUnit.Text);
                    el.resolution = [resVal resVal];
            end

            app.terminate();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElementCreatorUIFigure = uifigure('Visible', 'off');
            app.ElementCreatorUIFigure.Name = 'Element Creator';
            app.TabGroup = uitabgroup(app.ElementCreatorUIFigure);
            app.LensTab = uitab(app.TabGroup);
            app.LensTab.Title = 'Lens';
            app.LensButton = uibutton(app.LensTab, 'push');
            app.LensButton.ButtonPushedFcn = createCallbackFcn(app, @LensButtonPushed, true);
            app.LensButton.Text = 'Create';
            app.LensRequired = uipanel(app.LensTab);
            app.LensRequired.Title = 'Required';
            app.FocalLengthEditFieldLabel = uilabel(app.LensRequired);
            app.FocalLengthEditFieldLabel.Text = 'Focal Length';
            app.LensFocalLengthEditField = uieditfield(app.LensRequired, 'numeric');
            app.LensFocalLengthEditField.Value = 50;
            app.AptDiameterEditFieldLabel = uilabel(app.LensRequired);
            app.AptDiameterEditFieldLabel.Text = 'Apt. Diameter';
            app.LensAptDiameterEditField = uieditfield(app.LensRequired, 'numeric');
            app.LensAptDiameterEditField.Value = 1;
            app.DistanceLabel = uilabel(app.LensRequired);
            app.DistanceLabel.Text = 'Distance';
            app.LensDistanceEditField = uieditfield(app.LensRequired, 'numeric');
            app.LensDistanceEditField.Value = 50;
            app.LensDistanceEditFieldUnit = uilabel(app.LensRequired);
            app.LensDistanceEditFieldUnit.Text = 'mm';
            app.LensFocalLengthEditFieldUnit = uilabel(app.LensRequired);
            app.LensFocalLengthEditFieldUnit.Text = 'mm';
            app.LensAptDiameterEditFieldUnit = uilabel(app.LensRequired);
            app.LensAptDiameterEditFieldUnit.Text = 'mm';
            app.LensOptional = uipanel(app.LensTab);
            app.LensOptional.Title = 'Optional';
            app.SourceTab = uitab(app.TabGroup);
            app.SourceTab.Title = 'Source';
            app.SourceButton = uibutton(app.SourceTab, 'push');
            app.SourceButton.ButtonPushedFcn = createCallbackFcn(app, @SourceButtonPushed, true);
            app.SourceButton.Text = 'Create';
            app.SourceRequired = uipanel(app.SourceTab);
            app.SourceRequired.Title = 'Required';
            app.WavelengthEditFieldLabel = uilabel(app.SourceRequired);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.SourceWavelengthEditField = uieditfield(app.SourceRequired, 'numeric');
            app.SourceWavelengthEditField.Value = 632;
            app.SourceWavelengthEditFieldUnit = uilabel(app.SourceRequired);
            app.SourceWavelengthEditFieldUnit.Text = 'nm';
            app.SourceOptional = uipanel(app.SourceTab);
            app.SourceOptional.Title = 'Optional';
            app.DetectorTab = uitab(app.TabGroup);
            app.DetectorTab.Title = 'Detector';
            app.DetectorButton = uibutton(app.DetectorTab, 'push');
            app.DetectorButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorButtonPushed, true);
            app.DetectorButton.Text = 'Create';
            app.DetectorRequired = uipanel(app.DetectorTab);
            app.DetectorRequired.Title = 'Required';
            app.ResolutionEditFieldLabel = uilabel(app.DetectorRequired);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.DetectorResolutionEditField = uieditfield(app.DetectorRequired, 'numeric');
            app.DetectorResolutionEditField.AllowEmpty = 'on';
            app.DetectorResolutionEditField.Value = [];
            app.PixelPitchEditFieldLabel = uilabel(app.DetectorRequired);
            app.PixelPitchEditFieldLabel.Text = 'Pixel Pitch';
            app.DetectorPixelPitchEditField = uieditfield(app.DetectorRequired, 'numeric');
            app.DetectorPixelPitchEditField.AllowEmpty = 'on';
            app.DetectorPixelPitchEditField.Value = [];
            app.DistanceLabel_2 = uilabel(app.DetectorRequired);
            app.DistanceLabel_2.Text = 'Distance';
            app.DetectorDistanceEditField = uieditfield(app.DetectorRequired, 'numeric');
            app.DetectorDistanceEditField.Value = 100;
            app.DetectorDistanceEditFieldUnit = uilabel(app.DetectorRequired);
            app.DetectorDistanceEditFieldUnit.Text = 'mm';
            app.DetectorResolutionEditFieldUnit = uilabel(app.DetectorRequired);
            app.DetectorResolutionEditFieldUnit.Text = 'mm';
            app.DetectorPixelPitchEditFieldUnit = uilabel(app.DetectorRequired);
            app.DetectorPixelPitchEditFieldUnit.Text = 'μm';
            app.DetectorOptional = uipanel(app.DetectorTab);
            app.DetectorOptional.Title = 'Optional';
            % Show the figure after all components are created
            app.ElementCreatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElementCreationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElementCreatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElementCreatorUIFigure)
        end
    end
end