classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OSFAppUIFigure              matlab.ui.Figure
        FieldsPanel                 matlab.ui.container.Panel
        FieldsListBox               matlab.ui.control.ListBox
        EditFieldButton             matlab.ui.control.Button
        DelFieldButton              matlab.ui.control.Button
        AddFieldButton              matlab.ui.control.Button
        VisualsPanel                matlab.ui.container.Panel
        VisualsListBox              matlab.ui.control.ListBox
        EditVisualButton            matlab.ui.control.Button
        DelVisualButton             matlab.ui.control.Button
        AddVisualButton             matlab.ui.control.Button
        ElementsPanel               matlab.ui.container.Panel
        ElementsListBox             matlab.ui.control.ListBox
        EditElementButton           matlab.ui.control.Button
        DelElementButton            matlab.ui.control.Button
        AddElementButton            matlab.ui.control.Button
        SimulationSettingsPanel     matlab.ui.container.Panel
        ScanButton                  matlab.ui.control.Button
        PropagateButton             matlab.ui.control.Button
        PaddingRatioEditField       matlab.ui.control.NumericEditField
        PaddingRatioEditFieldLabel  matlab.ui.control.Label
        DimensionsEditField         matlab.ui.control.NumericEditField
        DimensionsEditFieldLabel    matlab.ui.control.Label
        FOVEditField                matlab.ui.control.NumericEditField
        FOVEditFieldLabel           matlab.ui.control.Label
        ResolutionEditField         matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel    matlab.ui.control.Label
        FOVUnitLabel                matlab.ui.control.Label
        ResolutionUnitLabel         matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        sim
    end

    methods (Access = private)

        function createElement(app, data)
            idx = data.insertIndex;
            elementID = app.sim.genElementID();

            switch data.elementType
                case 'source'
                    newElement = osf.elements.Source(data.wavelength, 'id', elementID, 'dim', app.sim.dim);
                    data.dist = 0;
                case 'lens'
                    newElement = osf.elements.Lens(data.focalLength, 'id', elementID, 'dim', app.sim.dim, 'circ', data.apertureSize);
                case 'detector'
                    newElement = osf.elements.Detector(app.sim.size(), app.sim.resolution, 'id', elementID, 'dim', app.sim.dim, data.varargin{:});
                otherwise
                    error("Support for elementType '%s' has not yet been implemented.", data.elementType);
            end

            % Insert into sim.elements and sim.distances
            if isempty(app.sim.elements)
                app.sim.elements = {newElement};
                app.sim.distances = [data.dist];
            else
                app.sim.elements = [app.sim.elements(1:idx-1), {newElement}, app.sim.elements(idx:end)];
                app.sim.distances = [app.sim.distances(1:idx-1), data.dist, app.sim.distances(idx:end)];
            end

            % Insert name into listbox
            if isempty(app.ElementsListBox.Items)
                app.ElementsListBox.Items = {char(newElement.name)};
            else
                app.ElementsListBox.Items = [ ...
                    app.ElementsListBox.Items(1:idx-1), ...
                    {char(newElement.name)}, ...
                    app.ElementsListBox.Items(idx:end)];
            end

            % Set selection to the newly added element
            app.ElementsListBox.Value = char(newElement.name);

            app.updateUI();
        end

        function updateUI(app)
            osf.vis.attachData(app.UIAxes, osf.vis.prepData(app.sim, 'default'));
        end

        
        function el = getSelectedElement(app)
            selectedName = app.ElementsListBox.Value;
            index = find(strcmp(app.ElementsListBox.Items, selectedName), 1);
            el = app.sim.elements{index};
        end
        
        function idx = getSelectedIndex(app)
            selectedName = app.ElementsListBox.Value;
            idx = find(strcmp(app.ElementsListBox.Items, selectedName), 1);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center app window in screen.
            screenSize = get(0, 'ScreenSize');
            figWidth = app.OSFAppUIFigure.Position(3);
            figHeight = app.OSFAppUIFigure.Position(4);
            app.OSFAppUIFigure.Position(1) = (screenSize(3) - figWidth) / 2;
            app.OSFAppUIFigure.Position(2) = (screenSize(4) - figHeight) / 2;
            app.sim = osf.Sim(app.ResolutionEditField.Value * 1e-6, app.FOVEditField.Value * 1e-3);
        end

        % Button pushed function: AddElementButton
        function AddElementButtonPushed(app, event)
            elementApp = osf.gui.ElementCreationApp;
            uiwait(elementApp.ElementCreatorUIFigure);

            if ~isvalid(elementApp)
                return;
            end

            elementData = elementApp.dataOut;
            delete(elementApp);

            if isempty(app.ElementsListBox.Items)
                insertIndex = 1;
            else
                currentName = app.ElementsListBox.Value;
                insertIndex = find(strcmp(app.ElementsListBox.Items, currentName)) + 1;
            end

            elementData.insertIndex = insertIndex;

            app.createElement(elementData);
        end

        % Button pushed function: EditElementButton
        function EditElementButtonPushed(app, event)
            selectedElement = app.getSelectedElement();
            selectedIdx = app.getSelectedIndex();
            
            elementApp = osf.gui.ElementCreationApp;

            dataIn.el = selectedElement;
            dataIn.dist = app.sim.distances(selectedIdx);
            elementApp.passData(dataIn);

            uiwait(elementApp.ElementCreatorUIFigure);

            if ~isvalid(elementApp)
                return;
            end

            dist = elementApp.dataOut.dist;
            delete(elementApp);

            app.sim.distances(selectedIdx) = dist;
            app.updateUI();
        end

        % Button pushed function: DelElementButton
        function DelElementButtonPushed(app, event)
            if isempty(app.ElementsListBox.Value)
                uialert(app.UIFigure, 'Please select an element to remove.', 'No Selection');
                return;
            end

            selectedElementName = app.ElementsListBox.Value;

            app.sim.removeElement(selectedElementName);

            updatedItems = app.ElementsListBox.Items;
            updatedItems(strcmp(updatedItems, selectedElementName)) = [];
            app.ElementsListBox.Items = updatedItems;
            app.updateUI();
        end

        % Button pushed function: PropagateButton
        function PropagateButtonPushed(app, event)
            app.sim.prop(app.sim.newField()).show();
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.sim.propScan(app.sim.newField(), 200, live=true);
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            value = app.ResolutionEditField.Value;
            app.sim.resolution = value;
        end

        % Value changed function: FOVEditField
        function FOVEditFieldValueChanged(app, event)
            value = app.FOVEditField.Value;
            app.sim.fieldLength = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OSFAppUIFigure = uifigure('Visible', 'off');
            app.OSFAppUIFigure.Name = 'OSF App';
            app.UIAxes = uiaxes(app.OSFAppUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SimulationSettingsPanel = uipanel(app.OSFAppUIFigure);
            app.SimulationSettingsPanel.Title = 'Simulation Settings';
            app.ResolutionUnitLabel = uilabel(app.SimulationSettingsPanel);
            app.ResolutionUnitLabel.Text = 'μm';
            app.FOVUnitLabel = uilabel(app.SimulationSettingsPanel);
            app.FOVUnitLabel.Text = 'mm';
            app.ResolutionEditFieldLabel = uilabel(app.SimulationSettingsPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.SimulationSettingsPanel, 'numeric');
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditField.Value = 2.5;
            app.FOVEditFieldLabel = uilabel(app.SimulationSettingsPanel);
            app.FOVEditFieldLabel.Text = 'FOV';
            app.FOVEditField = uieditfield(app.SimulationSettingsPanel, 'numeric');
            app.FOVEditField.ValueChangedFcn = createCallbackFcn(app, @FOVEditFieldValueChanged, true);
            app.FOVEditField.Value = 1;
            app.DimensionsEditFieldLabel = uilabel(app.SimulationSettingsPanel);
            app.DimensionsEditFieldLabel.Text = 'Dimensions';
            app.DimensionsEditField = uieditfield(app.SimulationSettingsPanel, 'numeric');
            app.DimensionsEditField.Value = 2;
            app.PaddingRatioEditFieldLabel = uilabel(app.SimulationSettingsPanel);
            app.PaddingRatioEditFieldLabel.Text = 'Padding Ratio';
            app.PaddingRatioEditField = uieditfield(app.SimulationSettingsPanel, 'numeric');
            app.PaddingRatioEditField.Value = 1;
            app.PropagateButton = uibutton(app.SimulationSettingsPanel, 'push');
            app.PropagateButton.ButtonPushedFcn = createCallbackFcn(app, @PropagateButtonPushed, true);
            app.PropagateButton.Text = 'Propagate';
            app.ScanButton = uibutton(app.SimulationSettingsPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ElementsPanel = uipanel(app.OSFAppUIFigure);
            app.ElementsPanel.Title = 'Elements';
            app.AddElementButton = uibutton(app.ElementsPanel, 'push');
            app.AddElementButton.ButtonPushedFcn = createCallbackFcn(app, @AddElementButtonPushed, true);
            app.AddElementButton.Text = 'Add';
            app.DelElementButton = uibutton(app.ElementsPanel, 'push');
            app.DelElementButton.ButtonPushedFcn = createCallbackFcn(app, @DelElementButtonPushed, true);
            app.DelElementButton.Text = 'Del';
            app.EditElementButton = uibutton(app.ElementsPanel, 'push');
            app.EditElementButton.ButtonPushedFcn = createCallbackFcn(app, @EditElementButtonPushed, true);
            app.EditElementButton.Text = 'Edit';
            app.ElementsListBox = uilistbox(app.ElementsPanel);
            app.ElementsListBox.Items = {};
            app.ElementsListBox.Value = {};
            app.VisualsPanel = uipanel(app.OSFAppUIFigure);
            app.VisualsPanel.Title = 'Visuals';
            app.AddVisualButton = uibutton(app.VisualsPanel, 'push');
            app.AddVisualButton.Text = 'Add';
            app.DelVisualButton = uibutton(app.VisualsPanel, 'push');
            app.DelVisualButton.Text = 'Rem';
            app.EditVisualButton = uibutton(app.VisualsPanel, 'push');
            app.EditVisualButton.Text = 'Edit';
            app.VisualsListBox = uilistbox(app.VisualsPanel);
            app.VisualsListBox.Items = {};
            app.VisualsListBox.Value = {};
            app.FieldsPanel = uipanel(app.OSFAppUIFigure);
            app.FieldsPanel.Title = 'Fields';
            app.AddFieldButton = uibutton(app.FieldsPanel, 'push');
            app.AddFieldButton.Text = 'Add';
            app.DelFieldButton = uibutton(app.FieldsPanel, 'push');
            app.DelFieldButton.Text = 'Rem';
            app.EditFieldButton = uibutton(app.FieldsPanel, 'push');
            app.EditFieldButton.Text = 'Edit';
            app.FieldsListBox = uilistbox(app.FieldsPanel);
            app.FieldsListBox.Items = {};
            app.FieldsListBox.Value = {};
            % Show the figure after all components are created
            app.OSFAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OSFAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OSFAppUIFigure)
        end
    end
end